classdef semestralnapracaGUI < matlab.apps.AppBase
    %SEMESTRALNAPRACAGUI tesla.mat

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Toolbar                 matlab.ui.container.Toolbar
        PushTool                matlab.ui.container.toolbar.PushTool
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        CenterPanel             matlab.ui.container.Panel
        SlightestLabel          matlab.ui.control.Label
        DifferenceLabel         matlab.ui.control.Label
        BestLabel               matlab.ui.control.Label
        WorstLabel              matlab.ui.control.Label
        AboveMedianLabel        matlab.ui.control.Label
        AboveLevelLabel         matlab.ui.control.Label
        MedianLabel             matlab.ui.control.Label
        PositiveLabel           matlab.ui.control.Label
        ValueLabel              matlab.ui.control.Label
        OdDoHodnoty             matlab.ui.control.RangeSlider
        OdDoHodnotySliderLabel  matlab.ui.control.Label
        loha10Button            matlab.ui.control.Button
        loha9Button             matlab.ui.control.Button
        loha8Button             matlab.ui.control.Button
        loha7Button             matlab.ui.control.Button
        loha6Button             matlab.ui.control.Button
        loha5Button             matlab.ui.control.Button
        loha4Button             matlab.ui.control.Button
        loha3Button             matlab.ui.control.Button
        loha2Button             matlab.ui.control.Button
        TlacitkoGraf            matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        RightPanel              matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        teslaData % % Data property for storing loaded data
        
    end
    
    methods (Access = private)
      % Load data from file
        function loadData(app, filename)
            app.teslaData = load(filename);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

% VYZNAČENIE HODNOT V GRAFE UIAxes
            % Load data from tesla.mat------------------------------------
            try
    % Load all variables from tesla.mat
    loadedData = load('tesla.mat');

    % Get the names of all variables
    variableNames = fieldnames(loadedData);

    % Look for the first numeric variable (assuming it contains the data)
    for i = 1:length(variableNames)
        variableName = variableNames{i};
        if isnumeric(loadedData.(variableName))
            % Assume this is the variable containing the data
            app.teslaData = loadedData.(variableName);

            % Exit the loop once the data is found
            break;
        end
    end

    % Check if the variable teslaData exists
    if ~isprop(app, 'teslaData')
        % Handle case where teslaData is not found
        errordlg('Variable teslaData not found in tesla.mat', 'Error');
        return;
    end

    % Assuming teslaData contains the data you want to plot
    x = 1:size(app.teslaData, 1); % x-axis values
    y = app.teslaData(:, 1); % y-axis values

    % Remove local maxima from the data
    smoothedData = smoothdata(y);
    localMaximaIndices = islocalmax(smoothedData);
    y(localMaximaIndices) = NaN;

    % Plot the data in the UIAxes
    plot(app.UIAxes, x, y);

    % Customize the plot as needed
    title(app.UIAxes, 'High Low Open Close');
    xlabel(app.UIAxes, 'X');
    ylabel(app.UIAxes, 'Y');
    
    % Set UIAxesVector to be initially invisible
    app.UIAxesVector.Visible = 'off';

    % Update UIAxes2 with average values
    updateUIAxes2(app);
    
catch exception
    % Handle errors, e.g., display an error message
    errordlg(['Error loading data from tesla.mat during startup: ' exception.message], 'Error');
end


        end

        % Button pushed function: TlacitkoGraf
        function TlacitkoGrafPushed(app, event)
    
%TLACITKO GRAF - ZOBRAZENIE HODNOT NA GRAFE UIAxes
            % Load data from tesla.mat
            try
        % Load all variables from tesla.mat
        loadedData = load('tesla.mat');

        % Get the names of all variables
        variableNames = fieldnames(loadedData);

        % Look for the first numeric variable (assuming it contains the data)
        for i = 1:length(variableNames)
            variableName = variableNames{i};
            if isnumeric(loadedData.(variableName))
                % Assume this is the variable containing the data
                app.teslaData = loadedData.(variableName);

                % Exit the loop once the data is found
                break;
            end
        end

        % Check if the variable teslaData exists
        if ~isprop(app, 'teslaData')
            % Handle case where teslaData is not found
            errordlg('Variable teslaData not found in tesla.mat', 'Error');
            return;
        end

        % Assuming teslaData contains the data you want to plot
        x = 1:size(app.teslaData, 1); % x-axis values
        y = app.teslaData(:, 1); % y-axis values

        % Remove local maxima from the data
        smoothedData = smoothdata(y);
        localMaximaIndices = islocalmax(smoothedData);
        y(localMaximaIndices) = NaN;

        % Plot the data in the UIAxes
        plot(app.UIAxes, x, y);

        % Customize the plot as needed
        title(app.UIAxes, 'High Low Open Close');
        xlabel(app.UIAxes, 'X');
        ylabel(app.UIAxes, 'Y');

        % Update UIAxes2 with average values
        updateUIAxes2(app);
        
    catch exception
        % Handle errors, e.g., display an error message
        errordlg(['Error loading data from tesla.mat during button push: ' exception.message], 'Error');
            end

        end

        % Button down function: UIAxes
        function UIAxesButtonDown3(app, event)
            load("tesla.mat")
        end

        % Value changed function: OdDoHodnoty
        function OdDoHodnotyValueChanged(app, event)
            
            
% OD - DO Hodnoty Slider
            % Get the slider values
    sliderValues = app.OdDoHodnoty.Value;

    % Assuming teslaData contains the data you want to plot
    x = 1:size(app.teslaData, 1); % x-axis values
    y = app.teslaData(:, 1); % y-axis values

    % Check if sliderValues is a valid range
    if numel(sliderValues) == 2 && diff(sliderValues) > 0
        % Plot the data in the UIAxes within the specified range
        plot(app.UIAxes, x, y);
        xlim(app.UIAxes, sliderValues);

        % Customize the plot as needed
        title(app.UIAxes, 'Tesla Size Plot');
        xlabel(app.UIAxes, 'X-Axis Label');
        ylabel(app.UIAxes, 'Y-Axis Label');

        % Update the ValueLabel to display the selected range and other information
        minVal = min(y(x >= sliderValues(1) & x <= sliderValues(2)));
        maxVal = max(y(x >= sliderValues(1) & x <= sliderValues(2)));
        app.ValueLabel.Text = ['Displayed Values: ' num2str(sliderValues(1)) ' to ' num2str(sliderValues(2)) ', Min: ' num2str(minVal) ', Max: ' num2str(maxVal)];
    else
        % Display an error message or handle the invalid range appropriately
        errordlg('Invalid slider range. Please select a valid range.', 'Error');
    end

     % Get the slider values
    sliderValues = app.OdDoHodnoty.Value;

    % Assuming teslaData contains the data you want to analyze
    x = 1:size(app.teslaData, 1); % x-axis values
    y_first_column = app.teslaData(:, 1); % values from the first column
    y_fourth_column = app.teslaData(:, 4); % values from the fourth column

    % Check if sliderValues is a valid range
    if numel(sliderValues) == 2 && diff(sliderValues) > 0
        % Filter data within the specified range
        selected_indices = x >= sliderValues(1) & x <= sliderValues(2);
        filtered_y_first_column = y_first_column(selected_indices);
        filtered_y_fourth_column = y_fourth_column(selected_indices);

        % Plot the data in the UIAxes within the specified range
        plot(app.UIAxes, x(selected_indices), y_first_column(selected_indices));
        xlim(app.UIAxes, sliderValues);

        % Count values from the fourth column that are higher than values from the first column
        count_positive_values = sum(filtered_y_fourth_column > filtered_y_first_column);

        % Update the PositiveLabel to display the count
        app.PositiveLabel.Text = ['Positive Values Count: ' num2str(count_positive_values)];
    else
        % Display an error message or handle the invalid range appropriately
        errordlg('Invalid slider range. Please select a valid range.', 'Error');
    end

    % Get the slider values
    sliderValues = app.OdDoHodnoty.Value;

    % Assuming teslaData contains the data you want to analyze
    x = 1:size(app.teslaData, 1); % x-axis values
    y_first_column = app.teslaData(:, 1); % values from the first column
    y_fourth_column = app.teslaData(:, 4); % values from the fourth column

    % Check if sliderValues is a valid range
    if numel(sliderValues) == 2 && diff(sliderValues) > 0
        % Filter data within the specified range
        selected_indices = x >= sliderValues(1) & x <= sliderValues(2);
        filtered_y_first_column = y_first_column(selected_indices);
        filtered_y_fourth_column = y_fourth_column(selected_indices);

        % Plot the data in the UIAxes within the specified range
        plot(app.UIAxes, x(selected_indices), y_first_column(selected_indices));
        xlim(app.UIAxes, sliderValues);

        % Count values from the fourth column that are higher than values from the first column
        count_positive_values = sum(filtered_y_fourth_column > filtered_y_first_column);

        % Update the PositiveLabel to display the count
        app.PositiveLabel.Text = ['Positive Values Count: ' num2str(count_positive_values)];

        % Calculate and display the median of the fourth column
        median_value = median(filtered_y_fourth_column);
        app.MedianLabel.Text = ['Median of Fourth Column: ' num2str(median_value)];
    else
        % Display an error message or handle the invalid range appropriately
        errordlg('Invalid slider range. Please select a valid range.', 'Error');
    end

    % Get the slider values
    sliderValues = app.OdDoHodnoty.Value;

    % Assuming teslaData contains the data you want to analyze
    x = 1:size(app.teslaData, 1); % x-axis values
    y_first_column = app.teslaData(:, 1); % values from the first column
    y_fourth_column = app.teslaData(:, 4); % values from the fourth column

    % Check if sliderValues is a valid range
    if numel(sliderValues) == 2 && diff(sliderValues) > 0
        % Filter data within the specified range
        selected_indices = x >= sliderValues(1) & x <= sliderValues(2);
        filtered_y_first_column = y_first_column(selected_indices);
        filtered_y_fourth_column = y_fourth_column(selected_indices);

        % Plot the data in the UIAxes within the specified range
        plot(app.UIAxes, x(selected_indices), y_first_column(selected_indices));
        xlim(app.UIAxes, sliderValues);

        % Count values from the fourth column that are higher than values from the first column
        count_positive_values = sum(filtered_y_fourth_column > filtered_y_first_column);

        % Update the PositiveLabel to display the count
        app.PositiveLabel.Text = ['Positive Values Count: ' num2str(count_positive_values)];

        % Calculate and display the median of the fourth column
        median_value = median(filtered_y_fourth_column);
        app.MedianLabel.Text = ['Median of Fourth Column: ' num2str(median_value)];

        % Count values from the fourth column that are higher than the median
        count_above_median = sum(filtered_y_fourth_column > median_value);

        % Update the AboveMedianLabel to display the count
        app.AboveMedianLabel.Text = ['Values Above Median Count: ' num2str(count_above_median)];
    else
        % Display an error message or handle the invalid range appropriately
        errordlg('Invalid slider range. Please select a valid range.', 'Error');
    end

    % Get the slider values
    sliderValues = app.OdDoHodnoty.Value;

    % Assuming teslaData contains the data you want to analyze
    x = 1:size(app.teslaData, 1); % x-axis values
    y_first_column = app.teslaData(:, 1); % values from the first column
    y_fourth_column = app.teslaData(:, 4); % values from the fourth column

    % Check if sliderValues is a valid range
    if numel(sliderValues) == 2 && diff(sliderValues) > 0
        % Filter data within the specified range
        selected_indices = x >= sliderValues(1) & x <= sliderValues(2);
        filtered_y_first_column = y_first_column(selected_indices);
        filtered_y_fourth_column = y_fourth_column(selected_indices);

        % Count values from the fourth column that are higher than values from the first column
        count_above_level = sum(filtered_y_fourth_column > filtered_y_first_column);

        % Update the AboveLevelLabel to display the count
        app.AboveLevelLabel.Text = ['Above Level Count: ' num2str(count_above_level)];
    else
        % Display an error message or handle the invalid range appropriately
        errordlg('Invalid slider range. Please select a valid range.', 'Error');
    end

   % Get the slider values
    sliderValues = app.OdDoHodnoty.Value;

    % Assuming teslaData contains the data you want to analyze
    x = 1:size(app.teslaData, 1); % x-axis values
    y_first_column = app.teslaData(:, 1); % values from the first column
    y_fourth_column = app.teslaData(:, 4); % values from the fourth column

    % Check if sliderValues is a valid range
    if numel(sliderValues) == 2 && diff(sliderValues) > 0
        % Filter data within the specified range
        selected_indices = x >= sliderValues(1) & x <= sliderValues(2);
        filtered_y_first_column = y_first_column(selected_indices);
        filtered_y_fourth_column = y_fourth_column(selected_indices);

        % Calculate the absolute differences
        differences = abs(filtered_y_first_column - filtered_y_fourth_column);

        % Find the maximum difference
        max_difference = max(differences);

        % Update the WorstLabel to display the maximum difference
        app.WorstLabel.Text = ['Max Difference: ' num2str(max_difference)];
    else
        % Display an error message or handle the invalid range appropriately
        errordlg('Invalid slider range. Please select a valid range.', 'Error');
    end

    % Get the slider values
    sliderValues = app.OdDoHodnoty.Value;

    % Assuming teslaData contains the data you want to analyze
    x = 1:size(app.teslaData, 1); % x-axis values
    y_first_column = app.teslaData(:, 1); % values from the first column
    y_fourth_column = app.teslaData(:, 4); % values from the fourth column

    % Check if sliderValues is a valid range
    if numel(sliderValues) == 2 && diff(sliderValues) > 0
        % Filter data within the specified range
        selected_indices = x >= sliderValues(1) & x <= sliderValues(2);
        filtered_y_first_column = y_first_column(selected_indices);
        filtered_y_fourth_column = y_fourth_column(selected_indices);

        % Calculate the increase in the first column values
        increases = diff(filtered_y_first_column);

        % Find the index of the value with the highest increase
        [~, max_increase_index] = max(increases);

        % Find the corresponding value in the fourth column
        corresponding_value_fourth_column = filtered_y_fourth_column(max_increase_index + 1);

        % Find the value from the first column with the highest increase
        highest_increase_value = filtered_y_first_column(max_increase_index);

        % Calculate and display the increase
        increase = highest_increase_value - corresponding_value_fourth_column;

        % Update the BestLabel to display the result
        app.BestLabel.Text = ['Value from First Column: ' num2str(highest_increase_value) ...
            ', Corresponding Value in Fourth Column: ' num2str(corresponding_value_fourth_column) ...
            ', Increase Positive Value: ' num2str(increase)];
    else
        % Display an error message or handle the invalid range appropriately
        errordlg('Invalid slider range. Please select a valid range.', 'Error');
    end

    % Get the slider values
    sliderValues = app.OdDoHodnoty.Value;

    % Assuming teslaData contains the data you want to analyze
    x = 1:size(app.teslaData, 1); % x-axis values
    y_second_column = app.teslaData(:, 2); % values from the second column
    y_third_column = app.teslaData(:, 3); % values from the third column

    % Check if sliderValues is a valid range
    if numel(sliderValues) == 2 && diff(sliderValues) > 0
        % Filter data within the specified range
        selected_indices = x >= sliderValues(1) & x <= sliderValues(2);
        filtered_y_second_column = y_second_column(selected_indices);
        filtered_y_third_column = y_third_column(selected_indices);

        % Calculate the differences between the second and third columns
        differences = abs(filtered_y_second_column - filtered_y_third_column);

        % Find the index of the maximum difference
        [~, max_difference_index] = max(differences);

        % Find the corresponding values in the second and third columns
        x_value_with_max_difference = x(selected_indices(max_difference_index));
        corresponding_value_second_column = filtered_y_second_column(max_difference_index);
        corresponding_value_third_column = filtered_y_third_column(max_difference_index);

        % Calculate and display the difference
        difference = corresponding_value_third_column - corresponding_value_second_column;

        % Update the DifferenceLabel to display the result
        app.DifferenceLabel.Text = ['Max Difference Value: ' num2str(difference) ...
            ', Chosen days from Range: ' num2str(x_value_with_max_difference)];
    else
        % Display an error message or handle the invalid range appropriately
        errordlg('Invalid slider range. Please select a valid range.', 'Error');
    end

    % Get the slider values
sliderValues = app.OdDoHodnoty.Value;

% Assuming teslaData contains the data you want to analyze
x = 1:size(app.teslaData, 1); % x-axis values
y_second_column = app.teslaData(:, 2); % values from the second column
y_third_column = app.teslaData(:, 3); % values from the third column

% Check if sliderValues is a valid range
if numel(sliderValues) == 2 && diff(sliderValues) > 0
    % Filter data within the specified range
    selected_indices = x >= sliderValues(1) & x <= sliderValues(2);
    filtered_y_second_column = y_second_column(selected_indices);
    filtered_y_third_column = y_third_column(selected_indices);

    % Calculate the absolute differences between the second and third columns
    differences = abs(filtered_y_second_column - filtered_y_third_column);

    % Find the index of the minimum difference
    [~, min_difference_index] = min(differences);

    % Find the corresponding values in the second and third columns
    x_value_with_min_difference = x(selected_indices(min_difference_index));
    corresponding_value_second_column = filtered_y_second_column(min_difference_index);
    corresponding_value_third_column = filtered_y_third_column(min_difference_index);

    % Calculate and display the difference
    difference = corresponding_value_third_column - corresponding_value_second_column;

    % Update the SlightestLabel to display the result
    app.SlightestLabel.Text = ['Min Difference Value: ' num2str(difference) ...
        ', Chosen days from Range: ' num2str(x_value_with_min_difference)];
else
    % Display an error message or handle the invalid range appropriately
    errordlg('Invalid slider range. Please select a valid range.', 'Error');
end

    
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {106, '1x', 58};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'Počet dní')
            ylabel(app.UIAxes, 'Návštevnosť')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown3, true);
            app.TlacitkoGraf = uibutton(app.CenterPanel, 'push');
            app.TlacitkoGraf.ButtonPushedFcn = createCallbackFcn(app, @TlacitkoGrafPushed, true);
            app.TlacitkoGraf.Text = 'Úloha č.1';
            app.loha2Button = uibutton(app.CenterPanel, 'push');
            app.loha2Button.Text = 'Úloha č. 2';
            app.loha3Button = uibutton(app.CenterPanel, 'push');
            app.loha3Button.Text = 'Úloha č. 3';
            app.loha4Button = uibutton(app.CenterPanel, 'push');
            app.loha4Button.Text = 'Úloha č. 4';
            app.loha5Button = uibutton(app.CenterPanel, 'push');
            app.loha5Button.Text = 'Úloha č. 5';
            app.loha6Button = uibutton(app.CenterPanel, 'push');
            app.loha6Button.Text = 'Úloha č. 6';
            app.loha7Button = uibutton(app.CenterPanel, 'push');
            app.loha7Button.Text = 'Úloha č. 7';
            app.loha8Button = uibutton(app.CenterPanel, 'push');
            app.loha8Button.Text = 'Úloha č. 8';
            app.loha9Button = uibutton(app.CenterPanel, 'push');
            app.loha9Button.Text = 'Úloha č. 9';
            app.loha10Button = uibutton(app.CenterPanel, 'push');
            app.loha10Button.Text = 'Úloha č. 10';
            app.OdDoHodnotySliderLabel = uilabel(app.CenterPanel);
            app.OdDoHodnotySliderLabel.Text = {'Od - Do'; 'Hodnoty'};
            app.OdDoHodnoty = uislider(app.CenterPanel, 'range');
            app.OdDoHodnoty.Limits = [0 2500];
            app.OdDoHodnoty.ValueChangedFcn = createCallbackFcn(app, @OdDoHodnotyValueChanged, true);
            app.OdDoHodnoty.Value = [0 800];
            app.ValueLabel = uilabel(app.CenterPanel);
            app.ValueLabel.Text = 'ValueLabel';
            app.PositiveLabel = uilabel(app.CenterPanel);
            app.PositiveLabel.Text = 'PositiveLabel';
            app.MedianLabel = uilabel(app.CenterPanel);
            app.MedianLabel.Text = 'MedianLabel';
            app.AboveLevelLabel = uilabel(app.CenterPanel);
            app.AboveLevelLabel.Text = 'AboveLevelLabel';
            app.AboveMedianLabel = uilabel(app.CenterPanel);
            app.AboveMedianLabel.Text = 'AboveMedianLabel';
            app.WorstLabel = uilabel(app.CenterPanel);
            app.WorstLabel.Text = 'WorstLabel';
            app.BestLabel = uilabel(app.CenterPanel);
            app.BestLabel.Text = 'BestLabel';
            app.DifferenceLabel = uilabel(app.CenterPanel);
            app.DifferenceLabel.Text = 'DifferenceLabel';
            app.SlightestLabel = uilabel(app.CenterPanel);
            app.SlightestLabel.Text = 'SlightestLabel';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = semestralnapracaGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end